classdef ShipOfTheseus < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        ExportLabel                     matlab.ui.control.TextArea
        flip                            matlab.ui.control.Image
        ExportDataButton                matlab.ui.control.Button
        IngredientControlPanel          matlab.ui.container.Panel
        AmountNeededEditField           matlab.ui.control.EditField
        AmountNeededEditFieldLabel      matlab.ui.control.Label
        gLabel_3                        matlab.ui.control.Label
        gLabel_2                        matlab.ui.control.Label
        gLabel                          matlab.ui.control.Label
        Lamp_6                          matlab.ui.control.Lamp
        TextArea_11                     matlab.ui.control.TextArea
        TextArea_10                     matlab.ui.control.TextArea
        CurrentWeightEditField          matlab.ui.control.NumericEditField
        CurrentWeightEditFieldLabel     matlab.ui.control.Label
        Lamp_5                          matlab.ui.control.Lamp
        Lamp_4                          matlab.ui.control.Lamp
        Lamp_3                          matlab.ui.control.Lamp
        Lamp_2                          matlab.ui.control.Lamp
        TextArea_9                      matlab.ui.control.TextArea
        TextArea_8                      matlab.ui.control.TextArea
        TextArea_7                      matlab.ui.control.TextArea
        TextArea_6                      matlab.ui.control.TextArea
        TextArea_5                      matlab.ui.control.TextArea
        TextArea_4                      matlab.ui.control.TextArea
        TextArea_3                      matlab.ui.control.TextArea
        TextArea_2                      matlab.ui.control.TextArea
        UITable                         matlab.ui.control.Table
        IngredientListDropDown          matlab.ui.control.DropDown
        IngredientListDropDownLabel     matlab.ui.control.Label
        TotalWeightValueEditField       matlab.ui.control.NumericEditField
        TotalWeightValueEditFieldLabel  matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        TareButton                      matlab.ui.control.Button
        TimerPanel                      matlab.ui.container.Panel
        FlipLamp                        matlab.ui.control.Lamp
        FlipLampLabel                   matlab.ui.control.Label
        pancake                         matlab.ui.control.Image
        skillet                         matlab.ui.control.Image
        DoneLamp                        matlab.ui.control.Lamp
        DoneLampLabel                   matlab.ui.control.Label
        TimerArea                       matlab.ui.control.TextArea
        ClearTimerButton                matlab.ui.control.Button
        Time2ndSideButton               matlab.ui.control.Button
        Time1stSideButton               matlab.ui.control.Button
        InstrumentationDataPanel        matlab.ui.container.Panel
        AnalogInputAI1EditField         matlab.ui.control.NumericEditField
        AnalogInputAI1EditFieldLabel    matlab.ui.control.Label
        VoltsLabel_3                    matlab.ui.control.Label
        AnalogInputAI0EditField         matlab.ui.control.NumericEditField
        AnalogInputAI0EditFieldLabel    matlab.ui.control.Label
        VoltsLabel                      matlab.ui.control.Label
        BlenderPanel                    matlab.ui.container.Panel
        DigitalOutputOnOffP10Switch     matlab.ui.control.ToggleSwitch
        TemperatureControlPanel         matlab.ui.container.Panel
        FLabel                          matlab.ui.control.Label
        Lamp                            matlab.ui.control.Lamp
        GriddleReadyLabel               matlab.ui.control.Label
        ActivateGriddleLabel            matlab.ui.control.Label
        LiveTempValueEditField          matlab.ui.control.NumericEditField
        AnalogInputAI1ValueLabel        matlab.ui.control.Label
        DigitalOutputOnOffP11Switch     matlab.ui.control.ToggleSwitch
        PANCAKEMANUFACTURINGINSTRUCTIONSPanel  matlab.ui.container.Panel
        PageEditField                   matlab.ui.control.NumericEditField
        PageEditFieldLabel              matlab.ui.control.Label
        NextButton                      matlab.ui.control.Button
        PreviousButton                  matlab.ui.control.Button
        TextArea                        matlab.ui.control.TextArea
    end


    properties (Access = public)
        %The properties section defines the variables that may be used
        %throughout the code. Outside of the "Properties" section, they
        %must be referenced using the prefix "app.".

        DaqAin % Analog Read object: will be used to read in analog voltages from NI 6001
        DaqDin % Digital Read object: will be used to read in digital voltages from NI 6001
        DaqAo % Analog Out object: will be used to write analog voltages signals from NI 6001
        DaqDo;%Digital Output object: will be used to write digital voltage signals (1=3.3V, 0= 0V) from NI 6001

        DigInput % Data of digital input channels (will be collection of 0's and 1's)
        AI_Voltages=[]; % collection of all analog input voltage data while app is running
        AI_Time=[]; % collection of all time data while app is running
        AI_Avg=[]; % collection of all timestamps and Analog input voltages average while app is running
        AI_StdDev=[]; % collection of all STDDEV of Analog input voltages  average while app is running
        Avg_Value=[];%% collection of all timestamps and Values average while app is running
        DI=[]; %collection of all digital input data while app is running
        data% analog Voltage input data read from DAQ board
        timestamps%time when analog Voltage input data was read from DAQ board
        digital_out_1_0=0;
        digital_out_1_1=0;%
        dummy_data=ones(100,2);
        LoadCellTare = 0;
        MilkTare = 0;
        EggTare = 0;
        FlourTare = 0;
        SugarTare = 0;
        ButterTare = 0;
        instructionstage = 1;
        instructionarray = {'Click "Activate Griddle" switch under "Temperature Control" to allow griddle to preheat. Click "Next".'...
            'Place ingredient cup onto loadcell then use "Tare" button to set a zero weight. Click "Next".'...
            'Individually Select the ingredients from the drop down list under "Ingredient Control" and carefully add the specified amount of ingredient to the blender cup before switching ingredients. Click "Next".'...
            'Repeat weighing for each ingredient. Once each Ingredient is an acceptable weight put blender cap on cup and place into blender. Turn cup so that it securely clicks into place. Click "Next".' ...
            'Flip the "Blend" switch. Wait for the Blender to stop automatically.Once the blender has stopped, pour 1/4 cup of batter onto griddle and click "Time 1st Side" button to start a 3 minute timer. Wait for the 1st side to finish, then flip pancake on griddle using a spatula. Click "Next".'...
            'Use the "Time 2nd Side" Button to start a timer for 2 minutes. Wait for the 2nd side to finish, then remove pancake from heat. Click "Next".'...
            'Serve and enjoy. Use "Export Data" button to export voltage, weight, and temperature data of the process as an excel sheet.'}
        heaterEnabled = 0;
        StopRequest = 0;
    end

    methods (Access = public)

        function A_initialize_daq(app)
            %Limitations of NI USB-6001
            % (1)can only command the Digital outputs to be on or off using the write command
            % (2)You cannot read analog input and read digital signals with the same daq object.
            % (3)Also cannot read analog signals and write either
            %digital or analog output signals from the same daq object
            %
            A1_setup_analog_inputs(app)
            A2_setup_digital_inputs(app)
            A3_setup_analog_outputs(app)
            A4_setup_digital_outputs(app)
            A5_start_data_acquisition(app)
        end

        function A1_setup_analog_inputs(app)
            app.DaqAin= daq('ni');%creates DataAcquisition Object for interfacing with all NI devices
            app.DaqAin.Rate = 1000;%scan rate in hertz (samples/second) that will be collected
            %add analog voltage channels

            % Set ScansAvailableFcn by defining "B_Process(app, src, event)"
            % that repeatively runs.
            app.DaqAin.ScansAvailableFcn = @(src,event) B_Process(app, src, event);
            app.DaqAin.ScansAvailableFcnCount = app.DaqAin.Rate/5;%Define how often 'B_Process' function is called (currently runs verytime 200 data points are collected, or 0.2 s if fsample=1000)
            addinput(app.DaqAin,'Dev2','ai0','Voltage');% add analog input channel, can repeat command to add additional analog input channels as long as you change the channel name
            addinput(app.DaqAin,'Dev2','ai1','Voltage');
        end

        function A2_setup_digital_inputs(app)
            app.DaqDin=daq('ni');% This DAQ object will be for Digital channels (High (1→3.3V) or Low (0→0V)) that can only be read on demand using the read function
            addinput(app.DaqDin,'Dev2','port0/line0','Digital'); % add digital input channel
        end

        function A3_setup_analog_outputs(app)
            app.DaqAo=daq('ni');% This DAQ object will be for Analog Voltage output channels that will be written on demand
            addoutput(app.DaqAo,'Dev2','ao0','Voltage');% add analog output voltage channel 0
            addoutput(app.DaqAo,'Dev2','ao1','Voltage');
            
        end

        function A4_setup_digital_outputs(app)
            app.DaqDo=daq('ni'); % This DAQ object will be for Digital Voltage output channels (High (1→3.3V) or Low (0→0V)) that will be written on demand
            addoutput(app.DaqDo,'Dev2','port1/line0','Digital'); % add digital output channelsadd
            addoutput(app.DaqDo,'Dev2','port1/line1','Digital');
        end

        function A5_start_data_acquisition(app)
            %write current value of AO0 Edit Fields to DAQ
            write(app.DaqDo,logical([app.digital_out_1_0 app.digital_out_1_1]));%send high or low signal to digital input port from digital output port
            start(app.DaqAin,"continuous");
        end


        function B_Process(app,src,~)
            [app.data,app.timestamps, ~] = read(src, src.ScansAvailableFcnCount, 'OutputFormat','Matrix');%the 'read' command reads in time and analog voltage data from daq every time the 'Process' function is called
            B1_check_user_inputs(app)
            B2_append_time_data_arrays(app)
            B3_store_avg_std_dev_data(app)
            B4_convert_voltage_signal_to_values(app)
            B5_export_data(app)
            B6_update_indicators_gauges(app)

            B7_write_signals(app)
            %             read_data(app)  % run 'read_data(app)' function
            %             write_data(app) % run 'write_data(app)' function
        end

        function B1_check_user_inputs(app)
            %This conditional statement determines whether the digital
            %output will be high (1→3.3V) or low (0→0V) based on app user's
            %input
            if strcmp(app.DigitalOutputOnOffP10Switch.Value,'On')
                app.digital_out_1_0=1;
            else
                app.digital_out_1_0=0;
            end
            %This conditional statement determines whether the digital
            %output will be high (1→3.3V) or low (0→0V) based on app user's
            %input
            if strcmp(app.DigitalOutputOnOffP11Switch.Value,'On')
               app.heaterEnabled = 1;
            else
               app.heaterEnabled = 0;
            end
        end

        function B2_append_time_data_arrays(app)
            app.AI_Time=[app.AI_Time; app.timestamps];%storing all time values
            app.AI_Voltages=[app.AI_Voltages ; app.data];%storing all voltage values
            app.DI=[app.DI; read(app.DaqDin,"OutputFormat","Matrix")];%read in current value of digital inputs and add to prior values of DIs

        end

        function B3_store_avg_std_dev_data(app)
            app.AI_Avg=[app.AI_Avg; mean(app.timestamps) mean(app.data)];%storing average voltage values
            app.AI_StdDev=[app.AI_StdDev; std(app.data)];%storing STD DEV of voltage values
        end

        function B4_convert_voltage_signal_to_values(app) % y = 154.66x - 220.49
            m_0=154.66;%slope for ai_0
            b_0=-220.49;%offset calibration constant for ai_0
            tare_0=app.LoadCellTare;%tared value for ai_0, this will have to be updated
            %app.Avg_Value=[app.Avg_Value; mean(app.timestamps) m_0*mean(app.data)+b_0-tare_0];%storing average value of signal after converting from volts to Force, Temp, etc. .

            m_1=200;%slope for ai_0
            b_1=-250;%offset calibration constant for ai_0
            %app.Avg_Value=[app.Avg_Value; mean(app.timestamps) m_1*mean(app.data)+b_1-tare_1];

            app.Avg_Value = [app.Avg_Value; mean(app.timestamps), m_0*mean(app.data(:,1))+b_0-tare_0, (m_1*mean(app.data(:,2))+b_1)*9/5+32];
            
        end

        function B5_export_data(~)
            %Insert code here to export signal data to files such as excel, matlab, etc.

            %writematrix(app.dummy_data,'export.csv');
        end

        function B6_update_indicators_gauges(app)

            
            %to update the user on the current voltage and value readings
            app.AnalogInputAI0EditField.Value=app.AI_Avg(end,2);%change indicator for AI0 voltage to current value
            app.TotalWeightValueEditField.Value=app.Avg_Value(end,2);

            app.AnalogInputAI1EditField.Value=app.AI_Avg(end,3);%change indicator for AI0 voltage to current value
            app.LiveTempValueEditField.Value=app.Avg_Value(end,3);
            %%The following if/else statements are used to change the color
            %of the app.DigitalInputOnOffP00Lamp when the DI it's High (3.3V→green) or
            %when its low (0 V→red)

            if app.Avg_Value(end,3) >= 370 && app.Avg_Value(end,3) <= 380
              app.Lamp.Color = [0 1 0];
            else
              app.Lamp.Color = [1 0 0];
            end


            if  app.instructionstage >= 1
                app.PageEditField.Value = app.instructionstage;
                app.TextArea.Value = app.instructionarray(app.instructionstage);
            end
            %drawnow

            %UPDATING BY INGREDIENT
            if strcmp(app.IngredientListDropDown.Value, 'Milk')
                % Calculate the remaining weight
                MilkW = abs(app.Avg_Value(end,2) - app.CurrentWeightEditField.Value);

                % Correct logical check
                if MilkW > 102 && MilkW < 142
                    app.Lamp_6.Color = [0.00, 1.00, 0.00]; % Green
                else
                    app.Lamp_6.Color = [1.00, 0.00, 0.00]; % Red
                end

            elseif strcmp(app.IngredientListDropDown.Value, 'Egg')
                % Calculate the remaining weight
                EggW = abs(app.Avg_Value(end,2) - app.CurrentWeightEditField.Value);

                % Correct logical check
                if EggW > 45 && EggW < 75
                    app.Lamp_2.Color = [0.00, 1.00, 0.00]; % Green
                else
                    app.Lamp_2.Color = [1.00, 0.00, 0.00]; % Red
                end

                elseif strcmp(app.IngredientListDropDown.Value, 'Flour')
                % Calculate the remaining weight
                FlourW = abs(app.Avg_Value(end,2) - app.CurrentWeightEditField.Value);

                % Correct logical check
                if FlourW > 45 && FlourW < 75
                    app.Lamp_3.Color = [0.00, 1.00, 0.00]; % Green
                else
                    app.Lamp_3.Color = [1.00, 0.00, 0.00]; % Red
                end

                elseif strcmp(app.IngredientListDropDown.Value, 'Sugar')
                % Calculate the remaining weight
                SugarW = abs(app.Avg_Value(end,2) - app.CurrentWeightEditField.Value);

                % Correct logical check
                if SugarW > -1 && SugarW < 8
                    app.Lamp_4.Color = [0.00, 1.00, 0.00]; % Green
                else
                    app.Lamp_4.Color = [1.00, 0.00, 0.00]; % Red
                end

                elseif strcmp(app.IngredientListDropDown.Value, 'Butter')
                % Calculate the remaining weight
                ButterW = abs(app.Avg_Value(end,2) - app.CurrentWeightEditField.Value);

                % Correct logical check
                if ButterW > 8 && ButterW < 24
                    app.Lamp_5.Color = [0.00, 1.00, 0.00]; % Green
                else
                    app.Lamp_5.Color = [1.00, 0.00, 0.00]; % Red
                end

            end
        end 
        

        function B7_write_signals(app)
              tempF = median(app.Avg_Value(end-9:end,3));
   
              lowLimit = 374;
              highLimit = 376;
   
           if app.heaterEnabled == 1
               if tempF <= lowLimit
                   app.digital_out_1_1 = 1;
               elseif tempF >= highLimit
                   app.digital_out_1_1 = 0;
               end
           else
               app.digital_out_1_1 = 0;
           end
        
           write(app.DaqDo,logical([app.digital_out_1_0 app.digital_out_1_1]))
           write(app.DaqAo,[app.AnalogOutputAO0EditField.Value,app.AnalogOutputAO1EditField.Value]);
            
        end
        function resetSwitch(app)
            % Ensure the UI component still exists before updating
            if isvalid(app) && isvalid(app.DigitalOutputOnOffP10Switch)
                app.DigitalOutputOnOffP10Switch.Value = 'Off'; % or 0/false

                % Explicitly call the callback if you want the "Off" logic to trigger
                % DigitalOutputOnOffP11SwitchValueChanged(app, []);
            end
        end


    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %This code executes when you open the app
            close all
            A_initialize_daq(app)%This function will connect the NI device to matlab and start data acquisition


        end

        % Button pushed function: PreviousButton
        function PreviousButtonPushed(app, event)
             if app.instructionstage > 0
            app.instructionstage = app.instructionstage - 1;
            end
        end

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)
            if app.instructionstage <7
            app.instructionstage = app.instructionstage + 1;
            end
        end

        % Button pushed function: Time1stSideButton
        function Time1stSideButtonPushed(app, event)
             app.skillet.Visible = "off";
            app.pancake.Visible = "on";
            app.flip.Visible = "off";
            app.FlipLamp.Visible = "on";
            app.FlipLampLabel.Visible = "on";
            app.DoneLamp.Visible = "off";
            app.DoneLampLabel.Visible = "off";
            app.FlipLamp.Color = "1.00,0.00,0.00";

            t = timer('ExecutionMode', 'fixedRate', ...
                'Period', 1, ...
                'TasksToExecute', 180, ...
                'BusyMode', 'drop');

            t.TimerFcn = @(src, event) updateTimer(app, src);
            t.StopFcn = @(src, event) finalizeTimer(app, src);

            start(t);

            function updateTimer(app, src)
                currentTime = 180 - src.TasksExecuted;
                min = num2str(fix(currentTime/60));
                sec = num2str(currentTime - 60*fix(currentTime/60));
                app.TimerArea.Value = min + ":" + sec;

                if app.StopRequest == 1
                    stop(t);
                    app.TimerArea.Value = '';
                end
            end

            function finalizeTimer(app, src)
                
                if app.StopRequest == 0
                    delete(src);
                    app.flip.Visible = "on";
                    app.pancake.Visible = "off";
                    app.FlipLamp.Color = "0.00,1.00,0.00";
                else
                    app.StopRequest =0;

                end
                app.TimerArea.Value = '';
                playAlarm(app,src)
            end
            function playAlarm(app,src)
                fs=8000;
                r = 0:1/fs:0.5;
                y = sin(2*pi*1000*r);
                sound(y, fs);
            end
        end

        % Button pushed function: Time2ndSideButton
        function Time2ndSideButtonPushed(app, event)
            app.skillet.Visible = "off";
            app.pancake.Visible = "on";
            app.flip.Visible = "off";
            app.FlipLamp.Visible = "off";
            app.FlipLampLabel.Visible = "off";
            app.DoneLamp.Visible = "on";
            app.DoneLampLabel.Visible = "on";
            app.FlipLamp.Color = "1.00,0.00,0.00";
            app.DoneLamp.Color = "1.00,0.00,0.00";

            t = timer('ExecutionMode', 'fixedRate', ...
                'Period', 1, ...
                'TasksToExecute', 120, ...
                'BusyMode', 'drop');

            t.TimerFcn = @(src, event) updateTimer(app, src);
            t.StopFcn = @(src, event) finalizeTimer(app, src);

            start(t);

            function updateTimer(app, src)
                currentTime = 120 - src.TasksExecuted;
                min = num2str(fix(currentTime/60));
                sec = num2str(currentTime - 60*fix(currentTime/60));
                app.TimerArea.Value = min + ":" + sec;

                if app.StopRequest == 1
                    stop(t);
                    app.TimerArea.Value = '';
                end
            end


            function finalizeTimer(app, src)
                
                if app.StopRequest == 0
                    delete(src);
                    app.skillet.Visible = "on";
                    app.pancake.Visible = "off";
                    app.DoneLamp.Color = "0.00,1.00,0.00";
                else
                    app.StopRequest =0;
                end
                app.TimerArea.Value = '';
                playAlarm(app,src)
            end
            function playAlarm(app,src)
                fs=8000;
                r = 0:1/fs:0.5;
                y = sin(2*pi*1000*r);
                sound(y, fs);
            end
           
            
        end

        % Button pushed function: ClearTimerButton
        function ClearTimerButtonPushed(app, event)
           app.StopRequest = 1;
           
        end

        % Value changed function: IngredientListDropDown
        function IngredientListDropDownValueChanged(app, event)
            value = app.IngredientListDropDown.Value;
            if value == "Milk"

            app.MilkTare = app.MilkTare + mean(app.Avg_Value(end,2));
            app.CurrentWeightEditField.Value = app.MilkTare;
            app.AmountNeededEditField.Value = "1/2 cup";
      
            elseif value == "Egg"

            app.EggTare = app.EggTare + mean(app.Avg_Value(end,2));
            app.CurrentWeightEditField.Value = app.EggTare;
            app.AmountNeededEditField.Value = "1 egg";

            elseif value == "Flour"

            app.FlourTare = app.FlourTare + mean(app.Avg_Value(end,2));
            app.CurrentWeightEditField.Value = app.FlourTare;
            app.AmountNeededEditField.Value = "1/2 cup flour";

            elseif value == "Sugar"

            app.SugarTare = app.SugarTare + mean(app.Avg_Value(end,2));
            app.CurrentWeightEditField.Value = app.SugarTare;
            app.AmountNeededEditField.Value = "1 tsp sugar";

            elseif value == "Butter"

            app.ButterTare = app.ButterTare + mean(app.Avg_Value(end,2));
            app.CurrentWeightEditField.Value = app.ButterTare;
            app.AmountNeededEditField.Value = "1 tbsp butter";

            else

            app.CurrentWeightEditField.Value = app.TotalWeightValueEditField.Value;
            app.AmountNeededEditField.Value = 0;

            end
        end

        % Button pushed function: TareButton
        function TareButtonPushed(app, event)
            app.LoadCellTare = app.LoadCellTare + mean(app.Avg_Value(end-5:end,2));
            app.TotalWeightValueEditField.Value = app.LoadCellTare;
        end

        % Button pushed function: ExportDataButton
        function ExportDataButtonPushed(app, event)
            header = {'Time', 'Load Cell Voltage (Voltage)', 'Thermocouple Voltage (V)', 'Weight(Grams)','Thermocouple Temperature (Celsius)'};
            data2export = array2table([app.AI_Avg, app.Avg_Value(:, 2), app.Avg_Value(:, 3)], 'VariableNames',header);
            writetable(data2export, 'PancakeManufacturingData.xlsx');

           app.ExportLabel.Value = "Data Exported!";
        end

        % Value changed function: DigitalOutputOnOffP10Switch
        function DigitalOutputOnOffP10SwitchValueChanged(app, event)
            value = app.DigitalOutputOnOffP10Switch.Value;
            if strcmp(value, 'On') || value == 1

                % 3. Create a timer to turn it off after 15 seconds
                t = timer('StartDelay', 15, 'ExecutionMode', 'singleShot');

                % 4. Define what happens when the timer finishes
                t.TimerFcn = @(~,~) resetSwitch(app);

                % 5. Start the timer
                start(t);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.PANCAKEMANUFACTURINGINSTRUCTIONSPanel = uipanel(app.UIFigure);
            app.PANCAKEMANUFACTURINGINSTRUCTIONSPanel.Title = 'PANCAKE MANUFACTURING INSTRUCTIONS';
            app.TextArea = uitextarea(app.PANCAKEMANUFACTURINGINSTRUCTIONSPanel);
            app.TextArea.Value = {'Press Next'; ''; ''};
            app.PreviousButton = uibutton(app.PANCAKEMANUFACTURINGINSTRUCTIONSPanel, 'push');
            app.PreviousButton.ButtonPushedFcn = createCallbackFcn(app, @PreviousButtonPushed, true);
            app.PreviousButton.Text = 'Previous';
            app.NextButton = uibutton(app.PANCAKEMANUFACTURINGINSTRUCTIONSPanel, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Text = 'Next';
            app.PageEditFieldLabel = uilabel(app.PANCAKEMANUFACTURINGINSTRUCTIONSPanel);
            app.PageEditFieldLabel.Text = 'Page';
            app.PageEditField = uieditfield(app.PANCAKEMANUFACTURINGINSTRUCTIONSPanel, 'numeric');
            app.TemperatureControlPanel = uipanel(app.UIFigure);
            app.TemperatureControlPanel.Title = 'Temperature Control';
            app.DigitalOutputOnOffP11Switch = uiswitch(app.TemperatureControlPanel, 'toggle');
            app.AnalogInputAI1ValueLabel = uilabel(app.TemperatureControlPanel);
            app.AnalogInputAI1ValueLabel.Text = 'Live Temp Value';
            app.LiveTempValueEditField = uieditfield(app.TemperatureControlPanel, 'numeric');
            app.ActivateGriddleLabel = uilabel(app.TemperatureControlPanel);
            app.ActivateGriddleLabel.Text = 'Activate Griddle:';
            app.GriddleReadyLabel = uilabel(app.TemperatureControlPanel);
            app.GriddleReadyLabel.Text = 'Griddle Ready?';
            app.Lamp = uilamp(app.TemperatureControlPanel);
            app.FLabel = uilabel(app.TemperatureControlPanel);
            app.FLabel.Text = '(°F)';
            app.BlenderPanel = uipanel(app.UIFigure);
            app.BlenderPanel.Title = 'Blender';
            app.DigitalOutputOnOffP10Switch = uiswitch(app.BlenderPanel, 'toggle');
            app.DigitalOutputOnOffP10Switch.ValueChangedFcn = createCallbackFcn(app, @DigitalOutputOnOffP10SwitchValueChanged, true);
            app.InstrumentationDataPanel = uipanel(app.UIFigure);
            app.InstrumentationDataPanel.Title = 'Instrumentation Data';
            app.VoltsLabel = uilabel(app.InstrumentationDataPanel);
            app.VoltsLabel.Text = '(Volts)';
            app.AnalogInputAI0EditFieldLabel = uilabel(app.InstrumentationDataPanel);
            app.AnalogInputAI0EditFieldLabel.Text = 'Analog Input (AI0)';
            app.AnalogInputAI0EditField = uieditfield(app.InstrumentationDataPanel, 'numeric');
            app.AnalogInputAI0EditField.Editable = 'off';
            app.VoltsLabel_3 = uilabel(app.InstrumentationDataPanel);
            app.VoltsLabel_3.Text = '(Volts)';
            app.AnalogInputAI1EditFieldLabel = uilabel(app.InstrumentationDataPanel);
            app.AnalogInputAI1EditFieldLabel.Text = 'Analog Input (AI1)';
            app.AnalogInputAI1EditField = uieditfield(app.InstrumentationDataPanel, 'numeric');
            app.AnalogInputAI1EditField.Editable = 'off';
            app.TimerPanel = uipanel(app.UIFigure);
            app.TimerPanel.Title = 'Timer';
            app.Time1stSideButton = uibutton(app.TimerPanel, 'push');
            app.Time1stSideButton.ButtonPushedFcn = createCallbackFcn(app, @Time1stSideButtonPushed, true);
            app.Time1stSideButton.Text = 'Time 1st Side';
            app.Time2ndSideButton = uibutton(app.TimerPanel, 'push');
            app.Time2ndSideButton.ButtonPushedFcn = createCallbackFcn(app, @Time2ndSideButtonPushed, true);
            app.Time2ndSideButton.Text = 'Time 2nd Side';
            app.ClearTimerButton = uibutton(app.TimerPanel, 'push');
            app.ClearTimerButton.ButtonPushedFcn = createCallbackFcn(app, @ClearTimerButtonPushed, true);
            app.ClearTimerButton.Text = 'Clear Timer';
            app.TimerArea = uitextarea(app.TimerPanel);
            app.DoneLampLabel = uilabel(app.TimerPanel);
            app.DoneLampLabel.Visible = 'off';
            app.DoneLampLabel.Text = 'Done?';
            app.DoneLamp = uilamp(app.TimerPanel);
            app.DoneLamp.Visible = 'off';
            app.skillet = uiimage(app.TimerPanel);
            app.skillet.ImageSource = fullfile(pathToMLAPP, 'Skillet.jpg');
            app.pancake = uiimage(app.TimerPanel);
            app.pancake.Visible = 'off';
            app.pancake.ImageSource = fullfile(pathToMLAPP, 'Pancakeimg.jpg');
            app.FlipLampLabel = uilabel(app.TimerPanel);
            app.FlipLampLabel.Visible = 'off';
            app.FlipLampLabel.Text = 'Flip?';
            app.FlipLamp = uilamp(app.TimerPanel);
            app.FlipLamp.Visible = 'off';
            app.IngredientControlPanel = uipanel(app.UIFigure);
            app.IngredientControlPanel.Title = 'Ingredient Control';
            app.TareButton = uibutton(app.IngredientControlPanel, 'push');
            app.TareButton.ButtonPushedFcn = createCallbackFcn(app, @TareButtonPushed, true);
            app.TareButton.Text = 'Tare';
            app.Label = uilabel(app.IngredientControlPanel);
            app.Label.Text = '';
            app.TotalWeightValueEditFieldLabel = uilabel(app.IngredientControlPanel);
            app.TotalWeightValueEditFieldLabel.Text = 'Total Weight Value';
            app.TotalWeightValueEditField = uieditfield(app.IngredientControlPanel, 'numeric');
            app.IngredientListDropDownLabel = uilabel(app.IngredientControlPanel);
            app.IngredientListDropDownLabel.Text = 'Ingredient List:';
            app.IngredientListDropDown = uidropdown(app.IngredientControlPanel);
            app.IngredientListDropDown.Items = {'All', 'Milk', 'Egg', 'Flour', 'Sugar', 'Butter'};
            app.IngredientListDropDown.ValueChangedFcn = createCallbackFcn(app, @IngredientListDropDownValueChanged, true);
            app.IngredientListDropDown.Value = 'All';
            app.UITable = uitable(app.IngredientControlPanel);
            app.UITable.ColumnName = {'Acceptable?'; 'Ingredient'};
            app.UITable.RowName = {};
            app.TextArea_2 = uitextarea(app.IngredientControlPanel);
            app.TextArea_2.Value = {'Egg'};
            app.TextArea_3 = uitextarea(app.IngredientControlPanel);
            app.TextArea_3.Value = {'Flour'};
            app.TextArea_4 = uitextarea(app.IngredientControlPanel);
            app.TextArea_4.Value = {'Sugar'};
            app.TextArea_5 = uitextarea(app.IngredientControlPanel);
            app.TextArea_5.Value = {'Butter'};
            app.TextArea_6 = uitextarea(app.IngredientControlPanel);
            app.TextArea_7 = uitextarea(app.IngredientControlPanel);
            app.TextArea_8 = uitextarea(app.IngredientControlPanel);
            app.TextArea_9 = uitextarea(app.IngredientControlPanel);
            app.Lamp_2 = uilamp(app.IngredientControlPanel);
            app.Lamp_3 = uilamp(app.IngredientControlPanel);
            app.Lamp_4 = uilamp(app.IngredientControlPanel);
            app.Lamp_5 = uilamp(app.IngredientControlPanel);
            app.CurrentWeightEditFieldLabel = uilabel(app.IngredientControlPanel);
            app.CurrentWeightEditFieldLabel.Text = 'Current Weight';
            app.CurrentWeightEditField = uieditfield(app.IngredientControlPanel, 'numeric');
            app.TextArea_10 = uitextarea(app.IngredientControlPanel);
            app.TextArea_11 = uitextarea(app.IngredientControlPanel);
            app.TextArea_11.Value = {'Milk'};
            app.Lamp_6 = uilamp(app.IngredientControlPanel);
            app.gLabel = uilabel(app.IngredientControlPanel);
            app.gLabel.Text = '(g)';
            app.gLabel_2 = uilabel(app.IngredientControlPanel);
            app.gLabel_2.Text = '(g)';
            app.gLabel_3 = uilabel(app.IngredientControlPanel);
            app.gLabel_3.Text = '(g)';
            app.AmountNeededEditFieldLabel = uilabel(app.IngredientControlPanel);
            app.AmountNeededEditFieldLabel.Text = 'Amount Needed';
            app.AmountNeededEditField = uieditfield(app.IngredientControlPanel, 'text');
            app.ExportDataButton = uibutton(app.UIFigure, 'push');
            app.ExportDataButton.ButtonPushedFcn = createCallbackFcn(app, @ExportDataButtonPushed, true);
            app.ExportDataButton.Text = 'Export Data';
            app.flip = uiimage(app.UIFigure);
            app.flip.Visible = 'off';
            app.flip.ImageSource = fullfile(pathToMLAPP, 'Flipping Pancake.jpg');
            app.ExportLabel = uitextarea(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ShipOfTheseus

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end